classdef ControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        QuadControlSwitchLabel          matlab.ui.control.Label
        QuadControlSwitch               matlab.ui.control.Switch
        SimulationControlpanelLabel     matlab.ui.control.Label
        MinDistanceTargetEditFieldLabel  matlab.ui.control.Label
        MinDistanceTargetEditField      matlab.ui.control.NumericEditField
        MinDistanceQuadEditFieldLabel   matlab.ui.control.Label
        MinDistanceQuadEditField        matlab.ui.control.NumericEditField
        SimulationdeltatimeEditFieldLabel  matlab.ui.control.Label
        SimulationdeltatimeEditField    matlab.ui.control.NumericEditField
        PredictionhorizontEditFieldLabel  matlab.ui.control.Label
        PredictionhorizontEditField     matlab.ui.control.NumericEditField
        DistpostargetscaleLabel         matlab.ui.control.Label
        DistpostargetscaleEditField     matlab.ui.control.NumericEditField
        DistposquadscaleEditFieldLabel  matlab.ui.control.Label
        DistposquadscaleEditField       matlab.ui.control.NumericEditField
        ControlParametersLabel          matlab.ui.control.Label
        MaxVelocityLabel                matlab.ui.control.Label
        MaxVelocityEditField            matlab.ui.control.NumericEditField
        MaxAccelerationEditFieldLabel   matlab.ui.control.Label
        MaxAccelerationEditField        matlab.ui.control.NumericEditField
        FormationListBoxLabel           matlab.ui.control.Label
        FormationListBox                matlab.ui.control.ListBox
        FormationscaleEditFieldLabel    matlab.ui.control.Label
        FormationscaleEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)

        SimulationActive 
        vrep 
        clientID 
        MaxAcceleration=0.5 
        MaxVelocity=6 
        MinDistanceFromTarget=1.2
        MinDistanceFromQuad=1.2
        SimulationDT=0.6
        PredictionHorizont=1
        DisturbTargetScale=0
        DisturbQuadScale=0
        
        
        QuadAmount=4
        FormationType=1
        FormationScale=1
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
        
    end
    

    methods (Access = private)

        % Value changed function: DistposquadscaleEditField
        function DistposquadscaleEditFieldValueChanged(app, event)
            app.DisturbQuadScale  = app.DistposquadscaleEditField.Value;            
        end

        % Value changed function: DistpostargetscaleEditField
        function DistpostargetscaleEditFieldValueChanged(app, event)
            app.DisturbTargetScale = app.DistpostargetscaleEditField.Value;            
        end

        % Value changed function: FormationListBox
        function FormationListBoxValueChanged(app, event)
            value = app.FormationListBox.Value;
            if strcmp(value,'4 quads, Square')
                app.FormationType=1;
                app.QuadAmount=4;
            end
            if strcmp(value,'8 quads, Square')
                app.FormationType=2;
                app.QuadAmount=8;
            end
            if strcmp(value,'8 quads, Octagon')
                app.FormationType=3;
                app.QuadAmount=8;
            end
        end

        % Value changed function: FormationscaleEditField
        function FormationscaleEditFieldValueChanged(app, event)
            app.FormationScale = app.FormationscaleEditField.Value;            
        end

        % Value changed function: MaxAccelerationEditField
        function MaxAccelerationEditFieldValueChanged(app, event)
            app.MaxAcceleration = app.MaxAccelerationEditField.Value;            
        end

        % Value changed function: MaxVelocityEditField
        function MaxVelocityEditFieldValueChanged(app, event)
            app.MaxVelocity = app.MaxVelocityEditField.Value;            
        end

        % Value changed function: MinDistanceQuadEditField
        function MinDistanceQuadEditFieldValueChanged(app, event)
            app.MinDistanceFromQuad = app.MinDistanceQuadEditField.Value;            
        end

        % Value changed function: MinDistanceTargetEditField
        function MinDistanceTargetEditFieldValueChanged(app, event)
            app.MinDistnaceFromTarget = app.MinDistanceTargetEditField.Value;            
        end

        % Value changed function: PredictionhorizontEditField
        function PredictionhorizontEditFieldValueChanged(app, event)
            app.PredictionHorizont = app.PredictionhorizontEditField.Value;            
        end

        % Value changed function: QuadControlSwitch
        function QuadControlSwitchValueChanged(app, event)
            value = app.QuadControlSwitch.Value;
            
            if strcmp(value,'On')
                app.vrep=remApi('remoteApi');
                app.vrep.simxFinish(-1);
                app.clientID= app.vrep.simxStart('127.0.0.1',19999,true,true,5000,5);
                if app.clientID<0
                    disp('Failed connecting to remote API server');
                    app.SimulationActive=0;
                    app.QuadControlSwitch.Value='Off';
                    app.vrep.delete();
                else
                    app.SimulationActive=1;
                    disp('Connected to remote API server');
                    % Parameters of simulation:
                    N=4;                    
                    sim_dt=app.SimulationDT;
                    sim_t=0;                    
                end
            end
            
            if strcmp(value,'Off')
                if  app.SimulationActive==1
                    app.vrep.simxGetPingTime(app.clientID);
                    app.vrep.simxFinish(app.clientID);
                    app.vrep.delete();
                    disp('Program ended');
                    app.SimulationActive=0;
                end                             
            end
            
            while (app.SimulationActive)
                
                if(sim_t<=0.1*sim_dt)
                    
                    if app.FormationType==1
                        app.QuadAmount=4;
                        N=app.QuadAmount;
                        TrgtFormation=[1.41;1.41;1.41;1.41 ].*app.FormationScale;
                        NeighboursFormation=[0 2 2.828 2; 2 0 2 2.828; 2.828 2 0 2; 2 2.828 2 0].*app.FormationScale;
                    end
                    
                    if app.FormationType==2
                        app.QuadAmount=8;
                        N=app.QuadAmount;
                        TrgtFormation=[1.41;1;1.41;1;1.41;1;1.41;1].*1.8*app.FormationScale;
                        NeighboursFormation=[0 1 2 2.236 2.828 2.236 2 1; 1 0 1 1.41 2.236 2 2.236 1.41;  2 1 0 1 2 2.236 2.828 2.236; 2.236 1.41 1 0 1 1.41 2.236 2; 2.828 2.236 2 1 0 1 2 2.236; 2.828 2 2.236 1.41 1 0 1 1.41; 2 2.236 2.828 2.236 2 1 0 1; 1 1.41 2.236 2 2.236 1.41 1 0; ].*1.8*app.FormationScale;
                    end
                    
                    if app.FormationType==3
                        app.QuadAmount=8;
                        N=app.QuadAmount;
                        TrgtFormation=[1.41;1;1.41;1;1.41;1;1.41;1].*1.8;
                        NeighboursFormation=[0 1 2 2.236 2.828 2.236 2 1; 1 0 1 1.41 2.236 2 2.236 1.41;  2 1 0 1 2 2.236 2.828 2.236; 2.236 1.41 1 0 1 1.41 2.236 2; 2.828 2.236 2 1 0 1 2 2.236; 2.828 2 2.236 1.41 1 0 1 1.41; 2 2.236 2.828 2.236 2 1 0 1; 1 1.41 2.236 2 2.236 1.41 1 0; ].*1.8;
                    end
                    
                    v_0=zeros(N,2);
                    
                    [main_trgt_posd,~]=GetDisturbedMainTrgtPosition(app.vrep,app.clientID,app.DisturbTargetScale);
                    [xd_0, ~]=GetDisturbedQuadPosition(app.vrep,app.clientID,N,app.DisturbQuadScale);
                    
                    
                    sim_dt=app.SimulationDT;
                    t= app.PredictionHorizont*sim_dt;
                    a_0=zeros(N,2,t/sim_dt);    
            
                    [trgt_alg_x, trgt_alg_y]=OptimizeNextMove( main_trgt_posd(1:2),TrgtFormation,NeighboursFormation,a_0,v_0,xd_0(:,1:2),t, app.MinDistanceFromTarget, app.MinDistanceFromQuad, app.MaxAcceleration, app.MaxVelocity);                    
                    tic;
                    sim_t=t;
                    idx=1;
                    posX=squeeze(xd_0(:,1));
                    posY=squeeze(xd_0(:,2));
                    
                    
                end
                
                for i=0:N-1
                    posX(i+1)=posX(i+1)+sim_dt*v_0(i+1,1)+0.5*trgt_alg_x(i+1,idx)*sim_dt^2;
                    posY(i+1)=posY(i+1)+sim_dt*v_0(i+1,2)+0.5*trgt_alg_y(i+1,idx)*sim_dt^2;
                    SetQuadTrgtPos(app.vrep, app.clientID, i, [posX(i+1) posY(i+1) xd_0(i+1,3)]);
                end
                
                app.vrep.simxSynchronousTrigger(app.clientID);
                
%                 elapsed_time=toc;
%                 sim_t=sim_t-elapsed_time;
%                 v_0(:,1)=v_0(:,1)+trgt_alg_x(:,idx)*elapsed_time;
%                 v_0(:,2)=v_0(:,2)+trgt_alg_y(:,idx)*elapsed_time;
                
                sim_t=sim_t-sim_dt;
                v_0(:,1)=v_0(:,1)+trgt_alg_x(:,idx)*sim_dt;
                v_0(:,2)=v_0(:,2)+trgt_alg_y(:,idx)*sim_dt;
                idx=idx+1;
                pause(0.01);
            end
            
            
        end

        % Value changed function: SimulationdeltatimeEditField
        function SimulationdeltatimeEditFieldValueChanged(app, event)
            app.SimulationDT = app.SimulationdeltatimeEditField.Value;           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.QuadControlSwitchLabel = uilabel(app.UIFigure);
            app.QuadControlSwitchLabel.Text = 'Quad Control';
            app.QuadControlSwitch = uiswitch(app.UIFigure, 'slider');
            app.QuadControlSwitch.ValueChangedFcn = createCallbackFcn(app, @QuadControlSwitchValueChanged, true);
            app.SimulationControlpanelLabel = uilabel(app.UIFigure);
            app.SimulationControlpanelLabel.Text = 'Simulation Control panel';
            app.MinDistanceTargetEditFieldLabel = uilabel(app.UIFigure);
            app.MinDistanceTargetEditFieldLabel.Text = 'Min Distance Target';
            app.MinDistanceTargetEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinDistanceTargetEditField.ValueChangedFcn = createCallbackFcn(app, @MinDistanceTargetEditFieldValueChanged, true);
            app.MinDistanceTargetEditField.Limits = [0 Inf];
            app.MinDistanceTargetEditField.Value = 1.2;
            app.MinDistanceQuadEditFieldLabel = uilabel(app.UIFigure);
            app.MinDistanceQuadEditFieldLabel.Text = 'Min Distance Quad';
            app.MinDistanceQuadEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinDistanceQuadEditField.ValueChangedFcn = createCallbackFcn(app, @MinDistanceQuadEditFieldValueChanged, true);
            app.MinDistanceQuadEditField.Limits = [0 Inf];
            app.MinDistanceQuadEditField.Value = 1.2;
            app.SimulationdeltatimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationdeltatimeEditFieldLabel.Text = 'Simulation delta time';
            app.SimulationdeltatimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationdeltatimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationdeltatimeEditFieldValueChanged, true);
            app.SimulationdeltatimeEditField.Limits = [0 Inf];
            app.SimulationdeltatimeEditField.Value = 0.6;
            app.PredictionhorizontEditFieldLabel = uilabel(app.UIFigure);
            app.PredictionhorizontEditFieldLabel.Text = 'Prediction horizont';
            app.PredictionhorizontEditField = uieditfield(app.UIFigure, 'numeric');
            app.PredictionhorizontEditField.ValueChangedFcn = createCallbackFcn(app, @PredictionhorizontEditFieldValueChanged, true);
            app.PredictionhorizontEditField.Limits = [0 12];
            app.PredictionhorizontEditField.ValueDisplayFormat = '%.0f';
            app.PredictionhorizontEditField.Value = 0.6;
            app.DistpostargetscaleLabel = uilabel(app.UIFigure);
            app.DistpostargetscaleLabel.Text = 'Dist. pos. target  scale %';
            app.DistpostargetscaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistpostargetscaleEditField.ValueChangedFcn = createCallbackFcn(app, @DistpostargetscaleEditFieldValueChanged, true);
            app.DistpostargetscaleEditField.Limits = [0 Inf];
            app.DistpostargetscaleEditField.ValueDisplayFormat = '%.0f';
            app.DistposquadscaleEditFieldLabel = uilabel(app.UIFigure);
            app.DistposquadscaleEditFieldLabel.Text = 'Dist. pos. quad  scale %';
            app.DistposquadscaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistposquadscaleEditField.ValueChangedFcn = createCallbackFcn(app, @DistposquadscaleEditFieldValueChanged, true);
            app.DistposquadscaleEditField.Limits = [0 Inf];
            app.DistposquadscaleEditField.ValueDisplayFormat = '%.0f';
            app.ControlParametersLabel = uilabel(app.UIFigure);
            app.ControlParametersLabel.Text = 'Control Parameters';
            app.MaxVelocityLabel = uilabel(app.UIFigure);
            app.MaxVelocityLabel.Text = 'Max Velocity';
            app.MaxVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxVelocityEditField.ValueChangedFcn = createCallbackFcn(app, @MaxVelocityEditFieldValueChanged, true);
            app.MaxVelocityEditField.Limits = [0 Inf];
            app.MaxVelocityEditField.Value = 6;
            app.MaxAccelerationEditFieldLabel = uilabel(app.UIFigure);
            app.MaxAccelerationEditFieldLabel.Text = 'Max Acceleration';
            app.MaxAccelerationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxAccelerationEditField.ValueChangedFcn = createCallbackFcn(app, @MaxAccelerationEditFieldValueChanged, true);
            app.MaxAccelerationEditField.Limits = [0 Inf];
            app.MaxAccelerationEditField.Value = 0.5;
            app.FormationListBoxLabel = uilabel(app.UIFigure);
            app.FormationListBoxLabel.Text = 'Formation';
            app.FormationListBox = uilistbox(app.UIFigure);
            app.FormationListBox.Items = {'4 quads, Square', '8 quads, Square'};
            app.FormationListBox.ValueChangedFcn = createCallbackFcn(app, @FormationListBoxValueChanged, true);
            app.FormationListBox.Value = '4 quads, Square';
            app.FormationscaleEditFieldLabel = uilabel(app.UIFigure);
            app.FormationscaleEditFieldLabel.Text = 'Formation scale';
            app.FormationscaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.FormationscaleEditField.ValueChangedFcn = createCallbackFcn(app, @FormationscaleEditFieldValueChanged, true);
            app.FormationscaleEditField.Limits = [0 Inf];
            app.FormationscaleEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = ControlApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end